classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AcquisizioneDatiTab            matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        SelezioneGestoDropDown         matlab.ui.control.DropDown
        SelezioneGestoDropDownLabel    matlab.ui.control.Label
        SelezioneManoDropDown          matlab.ui.control.DropDown
        SelezioneManoDropDownLabel     matlab.ui.control.Label
        SceglieretraleopzionidisponibiliLabel_2  matlab.ui.control.Label
        SceglieretraleopzionidisponibiliLabel  matlab.ui.control.Label
        CampoobbligatorioLabel_2       matlab.ui.control.Label
        CampoobbligatorioLabel         matlab.ui.control.Label
        NextButton                     matlab.ui.control.Button
        StopSaveButton                 matlab.ui.control.Button
        StartAcquisitionButton         matlab.ui.control.Button
        StatusTextArea                 matlab.ui.control.TextArea
        StatusTextAreaLabel            matlab.ui.control.Label
        SmartphoneModelEditField       matlab.ui.control.EditField
        SmartphoneModelEditFieldLabel  matlab.ui.control.Label
        PersonIDEditField              matlab.ui.control.EditField
        PersonIDEditFieldLabel         matlab.ui.control.Label
        ClassificazioneGestiTab        matlab.ui.container.Tab
    end

    % Proprietà private per la gestione della logica dell'app
    properties (Access = private)
        smartphone                      % Oggetto mobiledev
        signalsStruct = struct()        % Struttura dati
        currentGesture = 1              % Indice gesto corrente
        isAcquiring = false             % Flag acquisizione
        currentRep = 1                  % Ripetizione corrente
        availableSensorsStr             % Sensori disponibili
        allGestures                     % Lista completa dei gesti
        acquisitionInterrupted = false  % Flag interruzione
        globalAcquisitionCounter = 1    % Contatore globale
        startFromGesture = 1            % Indice gesto iniziale
        waitForNext = false             % Next per bloccare le acquisizioniù
    end
    
    % Metodi privati
    methods (Access = private)
        
        %% Metodo per ottenere una stringa con i sensori abilitati sul dispositivo mobile
        function availableSensorsStr = getAvailableSensors(app)
            sensors = [];
            % Controllo dello stato dei sensori
            if app.smartphone.AccelerationSensorEnabled, sensors = [sensors, 1]; end
            if app.smartphone.AngularVelocitySensorEnabled, sensors = [sensors, 2]; end
            if app.smartphone.MagneticSensorEnabled, sensors = [sensors, 3]; end
            if app.smartphone.OrientationSensorEnabled, sensors = [sensors, 4]; end
    
            % Conversione degli identificativi in stringhe e creazione della stringa finale
            sensorCells = arrayfun(@num2str, sensors, 'UniformOutput', false);
            % Unisce con il separatore '-'
            availableSensorsStr = strjoin(sensorCells, '-');

            % Aggiornamento dell'area di testo dello status
            app.StatusTextArea.Value = [app.StatusTextArea.Value; 
            {sprintf('Sensori disponibili: %s', availableSensorsStr)}];
        end
        
        %% Metodo per inizializzare il dispositivo mobile e configurare l'ambiente di acquisizione
        function initializeAcquisition(app)
            % Elimina inizializzazione smartphone se già presente
            if ~isempty(app.smartphone) && isvalid(app.smartphone)
                delete(app.smartphone);
                app.smartphone = [];
            end

            % Inizializza dispositivo mobile
            app.smartphone = mobiledev;
            
            % Configurazione sensori
            app.smartphone.AccelerationSensorEnabled = 1;
            app.smartphone.MagneticSensorEnabled = 1;
            app.smartphone.OrientationSensorEnabled = 1;
            app.smartphone.AngularVelocitySensorEnabled = 1;
            app.smartphone.SampleRate = 100;
            
            % Caricamento dei dati precedenti se esistono, altrimenti inizializzo la struttura
            if exist('signalsStructFile.mat', 'file')
                loadedData = load('signalsStructFile.mat');
                app.signalsStruct = loadedData.signalsStructFile;
            else
                app.signalsStruct = struct();
            end
            
            % Inizializza lista gesti
            app.allGestures = [arrayfun(@(x) sprintf('Class %d', x), 0:23, 'UniformOutput', false), {'Class 24'}];
        end

        %% Metodo principale per gestire l'acquisizione dei gesti e relative ripetizioni
        function startGestureAcquisition(app)
            % Inizializzazione del contatore globale e determinazione del gesto di partenza
            app.globalAcquisitionCounter = 1;
            app.startFromGesture = find(strcmp(app.SelezioneGestoDropDown.Value, app.allGestures), 1);
            
            % Verifica se il soggetto (PersonID) è già presente nella struttura dati
            if ~isfield(app.signalsStruct, app.PersonIDEditField.Value)
                app.signalsStruct.(app.PersonIDEditField.Value) = struct();
            end
            
            % Ciclo attraverso i gesti a partire da quello selezionato
            for gestureIdx = app.startFromGesture:length(app.allGestures)
                % Calcola l'indice progressivo corretto
                gestureID = app.allGestures{gestureIdx};
                baseIndex = (gestureIdx - app.startFromGesture) * 5;
                % Per ogni gesto, vengono effettuate 5 ripetizioni
                for rep = 1:5
                    % Se l'acquisizione è stata interrotta, esco dalla funzione
                    if ~app.isAcquiring, return; end
                    
                    % Determino il nome dell'acquisizione corrente in base al contatore globale
                    acqNumber = baseIndex + rep;
                    acqName = sprintf('acquisition_%d', acqNumber);

                    % Aggiorno l'area di status con il gesto e la ripetizione corrente
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                        {sprintf('Gesto: %s - Ripetizione: %d/5', gestureID, rep)}];
                    drawnow;
                    
                    % Imposto la condizione di attesa: abilito il pulsante "Next" e attendo l'interazione dell'utente
                    app.waitForNext = true;
                    app.NextButton.Enable = 'on';
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                        {'Premere "Next" per iniziare l''acquisizione'}];
                
                    % Ciclo di attesa
                    while isvalid(app) && app.waitForNext && app.isAcquiring
                        pause(0.1);
                        drawnow;
                    end
                    
                    % Se l'acquisizione è stata interrotta, esco dalla funzione
                    if ~app.isAcquiring, return; end

                    % Avvio dell'acquisizione dei dati per il gesto corrente
                    app.acquireData(gestureIdx, rep);
                    
                    % Se durante l'acquisizione si verifica un'interruzione, esco
                    if app.acquisitionInterrupted
                        return;
                    end

                end

                % Al termine delle 5 ripetizioni, chiedo all'utente se proseguire con il prossimo gesto
                if gestureIdx ~= length(app.allGestures) || rep ~= 5
                    choice = questdlg('Continuare con il prossimo gesto?', ...
                        'Conferma', 'Si', 'No', 'Si');
                    if strcmp(choice, 'No')
                        % Salvo i dati raccolti e termino l'acquisizione
                        app.saveData();
                        app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                            {'Acquisizioni completate per questo gesto!'}];
                        app.isAcquiring = false;
                        return;
                    end
                end
            end
            
            % Al termine di tutti i gesti, salvo i dati e aggiorno lo status
            app.saveData();
            app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                {'Acquisizioni completate per tutti i gesti!'}];
            app.isAcquiring = false;
        end
        
        %% Metodo per acquisire i dati dei sensori per il gesto e la ripetizione specificati
        function acquireData(app, gestureIdx, ~)
            try
                gestureID = app.allGestures{gestureIdx};
                % Avvio della registrazione dei dati sul dispositivo mobile
                app.smartphone.Logging = 1;
                pause(3.5); % Tempo di acquisizione (3.5 secondi)
                app.smartphone.Logging = 0;
                
                % Raccolta dati
                [acc, ~] = accellog(app.smartphone);
                [gyro, ~] = angvellog(app.smartphone);
                [mag, ~] = magfieldlog(app.smartphone);
                [orientation, ~] = orientlog(app.smartphone);
                app.smartphone.discardlogs; % Pulizia dei log dopo l'acquisizione 

                % Verifica che la quantità di dati acquisiti sia sufficiente
                if any([size(acc,1), size(gyro,1), size(mag,1), size(orientation,1)] < 250)
                    error('Acquisizione troppo corta!');
                end
                
                % Taglo dati a 250 campioni
                acc = acc(1:250,:);
                gyro = gyro(1:250,:);
                mag = mag(1:250,:);
                orientation = orientation(1:250,:);
                
                % Creazione di un nome univoco per l'acquisizione corrente
                acqName = sprintf('acquisition_%d', app.globalAcquisitionCounter);
                % Assicuro che il campo per il soggetto esista nella struttura
                if ~isfield(app.signalsStruct, app.PersonIDEditField.Value)
                    app.signalsStruct.(app.PersonIDEditField.Value) = struct();
                end
                
                % Salvataggio dei dati acquisiti e delle informazioni relative all'acquisizione
                app.signalsStruct.(app.PersonIDEditField.Value).(acqName) = struct(...
                    'acc', acc, ...
                    'gyro', gyro, ...
                    'mag', mag, ...
                    'orientation', orientation, ...
                    'GestureID', gestureID, ...
                    'Hand', app.SelezioneManoDropDown.Value, ...
                    'SmartphoneModel', app.SmartphoneModelEditField.Value, ...
                    'AvailableSensors', app.availableSensorsStr);
                
                % Aggiornamento del contatore globale delle acquisizioni
                app.globalAcquisitionCounter = app.globalAcquisitionCounter + 1;
                
            catch ME
                % In caso di errore, visualizzo il messaggio nell'area di status
                app.StatusTextArea.Value = [app.StatusTextArea.Value; {ME.message}];
                app.isAcquiring = false;
            end
        end
        
        %% Metodo per salvare i dati acquisiti su file (MAT e CSV)
        function saveData(app)
            % Salvataggio della struttura dati in un file MAT
            signalsStructFile = app.signalsStruct;
            save('signalsStructFile.mat', 'signalsStructFile');

            % Creazione delle annotazioni CSV con le informazioni relative alle acquisizioni
            csvData = {};
            fields = fieldnames(signalsStructFile);
            for i = 1:numel(fields)
                person = fields{i};
                acquisitions = fieldnames(signalsStructFile.(person));
                for j = 1:numel(acquisitions)
                    acq = signalsStructFile.(person).(acquisitions{j});
                    csvData{end+1} = {...
                        person,...                  % ID del soggetto
                        j,... % Index               % Indice dell'acquisizione
                        acq.Hand,...                % Mano usata (dx/sx)
                        acq.SmartphoneModel,...     % Modello dello smartphone
                        acq.AvailableSensors,...    % Sensori disponibili durante l'acquisizione
                        acq.GestureID,...           % Identificativo del gesto acquisito
                    };
                end
            end

            % Definizione delle intestazioni per il file CSV
            headers = {'ID_Subject','Idx_Acquisition','Hand','SmartphoneModel',...
                'Available_Sensors','ID_Gesture'};
    
            % Apertura e scrittura del file CSV
            file = fopen('Metadati.csv','w');
            fprintf(file,'%s\n',strjoin(headers,','));
            for k = 1:length(csvData)
                fprintf(file,'%s,%d,%s,%s,%s,%s\n',csvData{k}{:});
            end
            fclose(file);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Impostazioni iniziali per i pulsanti
            app.NextButton.Enable = 'off';
            app.StopSaveButton.Enable = "off";
        end

        % Button pushed function: StartAcquisitionButton
        function StartAcquisitionButtonPushed(app, event)
            % Pulizia iniziale dell'area di status
            app.StatusTextArea.Value = {''};
            % Disabilito il pulsante Start per evitare duplicazioni e abilito il pulsante Stop
            app.StartAcquisitionButton.Enable = 'off';  % Disabilita il tasto Start
            app.StopSaveButton.Enable = 'on';           % Abilita il tasto Stop

            % Verifica che i campi obbligatori siano stati compilati
            if isempty(app.PersonIDEditField.Value) || isempty(app.SmartphoneModelEditField.Value)
                app.StatusTextArea.Value = 'Compilare tutti i campi obbligatori!';
                if ~isempty(app.smartphone) && isvalid(app.smartphone)
                    clear app.smartphone;
                end
                app.isAcquiring = false;
                return;
            end

            % Caricamento dei dati precedenti (se esistenti)
            if exist('signalsStructFile.mat', 'file')
                loadedData = load('signalsStructFile.mat');
                app.signalsStruct = loadedData.signalsStructFile;
            end

            % Verifica se l'ID è già presente, chiede un nuovo ID se già esistente
            while isfield(app.signalsStruct, app.PersonIDEditField.Value)
                app.StatusTextArea.Value = {'ID già in uso! Cambiare il campo Person ID'};
                pause(1); % Pausa per evitare blocchi
                newID = inputdlg('Inserisci un nuovo ID:', 'ID già in uso', [1 50]);
                if isempty(newID) % Se l'utente annulla esce dalla funzione
                    return;
                end
                app.PersonIDEditField.Value = newID{1};
            end
            
            % Aggiornamento dello status per l'attivazione dei sensori
            app.StatusTextArea.Value = [app.StatusTextArea.Value;
                {sprintf('Attivazione sensori...')}];
            app.StopSaveButton.Enable = "on";

            % Resetta le variabili di stato del dispositivo
            app.isAcquiring = true;
            app.acquisitionInterrupted = false;
            app.globalAcquisitionCounter = 1; % Resetta contatore globale
            % Inizializza acquisizione
            try
                app.initializeAcquisition();
                app.availableSensorsStr = app.getAvailableSensors();
            catch ME
                % In caso di errore nell'inizializzazione, informo l'utente e termino
                app.StatusTextArea.Value = {'Errore nell''inizializzazione del dispositivo! Riprova.'};
                return;
            end 

            % Avvia acquisizione dal gesto selezionato
            app.StatusTextArea.Value = [app.StatusTextArea.Value;
                {sprintf('Avvio acquisizione dal gesto selezionato...')}];
            drawnow;
            startGestureAcquisition(app);
        end

        % Button pushed function: StopSaveButton
        function StopSaveButtonPushed(app, event)
            % Pulizia e aggiornamento dell'area di status
            app.StatusTextArea.Value = {''};
            % Imposto i flag per terminare l'acquisizione e segnalo l'interruzione
            app.isAcquiring = false;
            app.acquisitionInterrupted = true;
            % Salvo i dati attuali
            app.saveData();
            app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Acquisizione interrotta e dati salvati!'}];
            % Riabilito il pulsante Start e disabilito quelli Stop e Next
            app.StartAcquisitionButton.Enable = 'on';  % Riabilita il tasto Start
            app.StopSaveButton.Enable = 'off';         % Disabilita il tasto Stop
            app.NextButton.Enable = 'off';             % Disabilita il tasto Next
            % Pulizia del dispositivo mobile per evitare errori nelle future acquisizioni
            if ~isempty(app.smartphone) && isvalid(app.smartphone)
                app.smartphone.Logging = 0; % Assicuro che la registrazione sia fermata
                delete(app.smartphone);
                app.smartphone = [];
            end

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Aggiorno lo status e rimuovo la condizione di attesa per l'acquisizione successiva
            app.StatusTextArea.Value = {'Acquisizione in corso...'};
            app.waitForNext = false;
            app.NextButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisizioneDatiTab = uitab(app.TabGroup);
            app.AcquisizioneDatiTab.Title = 'Acquisizione Dati';
            app.GridLayout = uigridlayout(app.AcquisizioneDatiTab);
            app.PersonIDEditFieldLabel = uilabel(app.GridLayout);
            app.PersonIDEditFieldLabel.Text = 'Person ID';
            app.PersonIDEditField = uieditfield(app.GridLayout, 'text');
            app.PersonIDEditField.Placeholder = 'Es: P1 (P necessaria)';
            app.SmartphoneModelEditFieldLabel = uilabel(app.GridLayout);
            app.SmartphoneModelEditFieldLabel.Text = 'Smartphone Model';
            app.SmartphoneModelEditField = uieditfield(app.GridLayout, 'text');
            app.SmartphoneModelEditField.Placeholder = 'Es: "Samsung Galaxy S23"';
            app.StatusTextAreaLabel = uilabel(app.GridLayout);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.GridLayout);
            app.StatusTextArea.Placeholder = 'Qui appariranno informazioni sulle acquisizioni dei dati';
            app.StartAcquisitionButton = uibutton(app.GridLayout, 'push');
            app.StartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionButtonPushed, true);
            app.StartAcquisitionButton.Text = 'Start Acquisition';
            app.StopSaveButton = uibutton(app.GridLayout, 'push');
            app.StopSaveButton.ButtonPushedFcn = createCallbackFcn(app, @StopSaveButtonPushed, true);
            app.StopSaveButton.Text = 'Stop & Save';
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.CampoobbligatorioLabel = uilabel(app.GridLayout);
            app.CampoobbligatorioLabel.Text = {'Campo'; 'obbligatorio'};
            app.CampoobbligatorioLabel_2 = uilabel(app.GridLayout);
            app.CampoobbligatorioLabel_2.Text = {'Campo'; 'obbligatorio'};
            app.SceglieretraleopzionidisponibiliLabel = uilabel(app.GridLayout);
            app.SceglieretraleopzionidisponibiliLabel.Text = {'Scegliere tra le '; 'opzioni disponibili'};
            app.SceglieretraleopzionidisponibiliLabel_2 = uilabel(app.GridLayout);
            app.SceglieretraleopzionidisponibiliLabel_2.Text = {'Scegliere tra le '; 'opzioni disponibili'};
            app.SelezioneManoDropDownLabel = uilabel(app.GridLayout);
            app.SelezioneManoDropDownLabel.Text = 'Selezione Mano';
            app.SelezioneManoDropDown = uidropdown(app.GridLayout);
            app.SelezioneManoDropDown.Items = {'dx', 'sx'};
            app.SelezioneManoDropDown.Value = 'dx';
            app.SelezioneGestoDropDownLabel = uilabel(app.GridLayout);
            app.SelezioneGestoDropDownLabel.Text = 'Selezione Gesto';
            app.SelezioneGestoDropDown = uidropdown(app.GridLayout);
            app.SelezioneGestoDropDown.Items = {'Class 0', 'Class 1', 'Class 2', 'Class 3', 'Class 4', 'Class 5', 'Class 6', 'Class 7', 'Class 8', 'Class 9', 'Class 10', 'Class 11', 'Class 12', 'Class 13', 'Class 14', 'Class 15', 'Class 16', 'Class 17', 'Class 18', 'Class 19', 'Class 20', 'Class 21', 'Class 22', 'Class 23', 'Class 24'};
            app.SelezioneGestoDropDown.Value = 'Class 0';
            app.ClassificazioneGestiTab = uitab(app.TabGroup);
            app.ClassificazioneGestiTab.Title = 'Classificazione Gesti';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end